classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % UI Figure
        TabGroup              matlab.ui.container.TabGroup       % Melakart...
        Tab                   matlab.ui.container.Tab            % Melakart...
        Label                 matlab.ui.control.Label            % Explore ...
        Label2                matlab.ui.control.Label           
        LabelDropDown         matlab.ui.control.Label            % Choose t...
        DropDown              matlab.ui.control.DropDown         % Kanakaan...
        Nonotesinraga         matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField matlab.ui.control.Label           
        Nograhabhedamragas_melakarta matlab.ui.control.NumericEditField % [...
        NonotesButton         matlab.ui.control.Button           % The numb...
        Nograhabhedamragas_melakartaButton matlab.ui.control.Button % No of...
        Nograhabhedamragas_SaPaVarjyamButton matlab.ui.control.Button % No ...
        Label3                matlab.ui.control.Label            % Graha Bh...
        Nograhabhedamragas_SaPaVarjyam matlab.ui.control.NumericEditField
        InputRaga             matlab.ui.control.Button           % Listen t...
        OnShrutiBhedam        matlab.ui.control.Button           % Click to...
        Tab2                  matlab.ui.container.Tab            % Symmetri...
        LabelDropDown2        matlab.ui.control.Label            % Choose t...
        DropDown2             matlab.ui.control.DropDown         % Sudha Sa...
        InputRagaListen       matlab.ui.control.Button           % Listen t...
        NoNotesinRagaButton   matlab.ui.control.Button           % The numb...
        NoGrahaBhedamRagasButton matlab.ui.control.Button % Number of Graha...
        NoNotesInRaga         matlab.ui.control.NumericEditField % [-Inf Inf]
        NoGrahaBhedamRagas    matlab.ui.control.NumericEditField % [-Inf Inf]
        OnGrahaBhedam         matlab.ui.control.Button           % Click to...
        Label5                matlab.ui.control.Label            % Explore ...
        Tab3                  matlab.ui.container.Tab            % Asymmetr...
        Label4                matlab.ui.control.Label            % Choose t...
        DropDown3             matlab.ui.control.DropDown         % Aarabi, ...
        Button                matlab.ui.control.Button           % Listen t...
        Button2               matlab.ui.control.Button           % Number o...
        NoGrahaBhedamRagasAj  matlab.ui.control.NumericEditField % [-Inf Inf]
        Button3               matlab.ui.control.Button           % Click to...
        Label6                matlab.ui.control.Label            % Explore ...
    end


    properties (Access = public)
        a
     shruti
     T
     no_of_notes_in_raga
     nocols_cmelakarta
     
     
     J
     norows_J
     aj
     no_of_notes_in_raga_janya
     nocols_cjanya
     nocols_cjanyaspv
     
     a1
     a2
     Aj
     
     nocols_c1janya
     nocols_c2janya
     
    end

    properties (Access = private)
        Property3 % Description
        Property4 % Description
        Property % Description
    end

    methods (Access = public)
        
        function results = func(app)
            
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


        end

        % DropDown value changed function
        function choose_dropdown(app, event)
            value = app.DropDown.Value;

            atemp=zeros(1,12);
            
            app.T=cell(72, 2);

            
            load('testovaci_melakarta72', 'melakarta72');
            
            app.T=melakarta72;

            load('testovaci_janyaragas', 'janyaragas')

            app.J=janyaragas;
       [app.norows_J,~]=size(app.J);
            
            
           % T(1,:)={'Mohanam', [1 0 1 0 1 0 0 1 0 1 0 0]};
            %T(2,:)={'Madhyamavathi', [1 0 1 0 0 1 0 1 0 0 1 0]};
            %T(3,:)={'Hindolam', [1 0 0 1 0 1 0 0 1 0 1 0 ]};
            %T(4,:)={'Sudha Saveri', [1 0 1 0 0 1 0 1 0 1 0 0]};
            %T(5,:)={'Sudha Dhanyasi', [1 0 0 1 0 1 0 1 0 0 1 0]};
        
            for i=1:72
                if strcmp(value, app.T(i,1))
                 atemp = cell2mat(app.T(i,2));
                end
            end
            app.a = atemp;

        end

        % InputRaga button pushed function
        function Input_raga(app)


%production of bshruti vector with original notes

 %app.a= [1 ; 0; 1; 0; 1; 0; 1; 1; 0; 1; 0; 1] 


shrutitemp = [261.62, 277.18, 293.66, 311.12, 329.62, 349.22, 369.99, 391.99, 415.30, 440.00, 466.16, 493.88];
app.shruti=shrutitemp';

ainteriminterim=app.a;
ainterim=ainteriminterim';

bshruti=zeros(12,1);

bshruti=ainterim.*app.shruti;


noelements_in_bshrutinonzeros=length(nonzeros(bshruti(:,1)));
bshrutinonzeros = zeros(noelements_in_bshrutinonzeros,1);
bshrutinonzeros=nonzeros(bshruti(:,1));
bshrutinonzeros = [bshrutinonzeros; 523.25];


no_of_notes_bshrutinonzeros=length(bshrutinonzeros);
[reff,ceff]=size(bshrutinonzeros);

notes=[bshrutinonzeros;0;(fliplr(bshrutinonzeros'))'];
no_of_notes=length(notes);


%  Generating the sound of the raga

Fs        =  12000;  % sampling frequency
repeat    =  1;     % no. of repeatition you want to hear the raga
tim       =  1;      % hear each swara/note for 1 second


%duration of each swara
dur = ones(no_of_notes,1);


% amplitude of sound of each note
amp   = 48 * ones(no_of_notes,1)./60;


% no. of harmonics
nharm = 8;   % (if nharm is 2, note freq f and 2f are added to sound)

% scaling the amplitude of harmonics (exp(1-n)/fac))
fac   = 1;
sig = [];
for j = 1:repeat
for i = 1:no_of_notes
x = generate_tone(notes(i),tim*dur(i),amp(i),Fs,nharm,fac);
sig = horzcat(sig,x);
end
end


app.no_of_notes_in_raga=reff-1;


fprintf('The input raga sounds like\n')
pause(5)
sound(sig,Fs)
pause(10)

audiowrite('Kamavardhini.wav',sig,Fs)


        end

        % OnShrutiBhedam button pushed function
        function Shrutibhedam(app)
            
shrutitemp = [261.62, 277.18, 293.66, 311.12, 329.62, 349.22, 369.99, 391.99, 415.30, 440.00, 466.16, 493.88];
app.shruti=shrutitemp';

%Circular shifting

ainterim=app.a';


b=zeros(13,11);

for i=1:11

b(1,i) = i+1;
b([2:13],i)= circshift(ainterim,12-i);

end


s=sum(b(2,1:11));

c=zeros(13,s);

t=0;

for (i=1:11)
    if b(2,i)==1
        t=t+1;
        c(:,t)=b(:,i);
    end
end


c=unique(c','rows')';


%c of 13 cross 6 matrix is generated in which the first row is the swara
%from which Graha bhedam is done
%the rest 12 rows are the 1, 0 binary notation
%there are totally 6 columns which talk about the 6 new scales formed by
%graha bhedam, the 6 columns start with 1, i.e there is definitely a
%Shadjam.

cmelakarta=[];

[norows_c,nocols_c]=size(c);

cmelakarta_colindex=0;

for i=1:nocols_c
 
    tempcolvector=c(:,i);
    temprowvector=tempcolvector';
    
    for melno=1:72
        if isequal(cell2mat(app.T(melno,2)),temprowvector(1,[2:13]))
            cmelakarta_colindex = cmelakarta_colindex + 1;
            cmelakarta(1,cmelakarta_colindex) = temprowvector(1,1);
            cmelakarta(2,cmelakarta_colindex) = melno;
            cmelakarta([3:14],cmelakarta_colindex)=temprowvector(1,[2:13])';
        end
    end
            
    
end

norows_cmelakarta=0;

app.nocols_cmelakarta=0;

[norows_cmelakarta,app.nocols_cmelakarta]=size(cmelakarta);

fprintf('The number of melakarta ragas produced on Graha Bhedam are %d\n',app.nocols_cmelakarta)

    %app.Nograhabhedamragas_melakarta.Value=app.nocols_cmelakarta


%Production of cshruti matrix with zeros for the shifted ragas


cshruti=zeros(15,app.nocols_cmelakarta);

for i = 1:app.nocols_cmelakarta
    cshruti(1,i)=cmelakarta(1,i);
    cshruti(2,i)=cmelakarta(2,i);
    cshruti([3:14],i)=cmelakarta([3:14],i).*app.shruti.*((1.059463)^(cmelakarta(1,i)-1));
    cshruti(15,i)= 523.25.*((1.059463)^(cmelakarta(1,i)-1));
end


%Generation of cshrutinonzeros matrix

noelements_in_cshrutinonzeros=length(nonzeros(cshruti(:,1)));
cshrutinonzeros = zeros(noelements_in_cshrutinonzeros,app.nocols_cmelakarta);

for i=1:app.nocols_cmelakarta
    cshrutinonzeros(1,i)=cshruti(1,i);
    cshrutinonzeros(2,i)=cshruti(2,i);
    cshrutinonzeros([3:noelements_in_cshrutinonzeros],i)=nonzeros(cshruti([3:15],i));
end


[reff,ceff]=size(cshrutinonzeros);


%Tabulation of Note Number and Swara Name

N=cell(12, 2);
          
           N(1,:)={'Sa', 1};
           N(2,:)={'Ri 1', 2};
           N(3,:)={'Ri 2', 3};
           N(4,:)={'Ga 2', 4};
           N(5,:)={'Ga 3', 5};
           N(6,:)={'Ma 1', 6};
           N(7,:)={'Ma 2', 7};
           N(8,:)={'Pa', 8};
           N(9,:)={'Dha 1', 9};
           N(10,:)={'Dha 2', 10};
           N(11,:)={'Ni 2', 11};
           N(12,:)={'Ni 3', 12};


SNo=zeros(ceff,1);          
%Graha_Bhedam_From_Swara=strings([ceff,1]);
Yields_MelakartaNo=zeros(ceff,1);
%MelakartaName=strings([ceff,1]);  
           
Graha_Bhedam_From_Swara=cell(ceff,1);
MelakartaName=cell(ceff,1);

for tdim=1:ceff

notes=[cshrutinonzeros([3:reff],tdim);0;(fliplr(cshrutinonzeros([3:reff],tdim)'))'];
no_of_notes=length(notes);

%  Generating the sound of the raga

Fs        =  12000;  % sampling frequency
repeat    =  1;     % no. of repeatition you want to hear the raga
tim       =  1;      % hear each swara/note for 1 second


%duration of each swara
dur = ones(no_of_notes,1);


% amplitude of sound of each note
amp   = 48 * ones(no_of_notes,1)./60;


% no. of harmonics
nharm = 8;   % (if nharm is 2, note freq f and 2f are added to sound)

% scaling the amplitude of harmonics (exp(1-n)/fac))
fac   = 1;
m = [];
for j = 1:repeat
for i = 1:no_of_notes
x = generate_tone(notes(i),tim*dur(i),amp(i),Fs,nharm,fac);
m = horzcat(m,x);
end
end


noteno=cshrutinonzeros(1,tdim);

%for initializing with random note Ga1
note_name='Ga 1';

for i=1:12
    if i==noteno
        note_name=N(i,1);
    end
end

%note_name=cell2mat(notename);
%note_name_string is the swara name like Sa, Ri, Ga, Ma

%curly braces at end of line 372
note_name_string=note_name{1};

melakarta_no = cshrutinonzeros(2,tdim);

melakarta_name_incell=app.T(melakarta_no,1);

%curly braces at end of line 378
melakarta_name=melakarta_name_incell{1};


SNo(tdim,1)=tdim;
Yields_MelakartaNo(tdim,1)=melakarta_no;
Graha_Bhedam_From_Swara(tdim,1)=note_name;
MelakartaName(tdim,1)=melakarta_name_incell;


fprintf('%d. The notes when shifted from the swara %s give the scale of melakarta no. % d %s \n', tdim,note_name_string,melakarta_no,melakarta_name) 

sound(m,Fs)
pause(20)


end


%Cell to string
Shruthi_Bhedam_From_Swara=char(Graha_Bhedam_From_Swara);
Melakarta_Raga=char(MelakartaName);

%tout = table('size',[ceff 4],'VariableTypes',{'int8','string','int8','string'});          
% tout = table(SNo,Shruthi_Bhedam_From_Swara,Yields_MelakartaNo,Melakarta_Raga);


%All converted to cell form

SNo_cell=num2cell(SNo);
Yields_MelakartaNo_cell=num2cell(Yields_MelakartaNo);


Graha_Bhedam_Ragas=table(SNo_cell, Graha_Bhedam_From_Swara, Yields_MelakartaNo_cell, MelakartaName);
Graha_Bhedam_Melakarta_Ragas=table(SNo_cell, Graha_Bhedam_From_Swara, Yields_MelakartaNo_cell, MelakartaName)

Uitable_data=[Graha_Bhedam_From_Swara Yields_MelakartaNo_cell MelakartaName];

% f=uifigure;
% uit = uitable('Parent', f,'Data',magic(10))


f = figure('Position', [100 100 752 250]);

t = uitable('Parent', f, 'Position', [25 50 700 200], 'Data', Uitable_data);
t.ColumnName = {'Graha Bhedam from Swara', 'Yields Melakarta No.', 'Melakarta Raga'};
t.ColumnWidth = {'auto', 'auto', 300};
t.FontSize=12;

%uit.ColumnName = {'SNo','Swara','Mela No','Raga'};


%Commencement of work on Shadjama Panchama Varjyam


load('testovaci_janyaragas', 'janyaragas')

app.J=janyaragas;

%Specific Segment for Shadjama Panchama Varjyam
%Circular shifting

ainterimspv=app.a';

ainterimspv(1,1)=0;
ainterimspv(8,1)=0;

bspv=zeros(13,11);

for i=1:11

bspv(1,i) = i+1;
bspv([2:13],i)= circshift(ainterimspv,12-i);

end


sspv=sum(bspv(2,1:11));

cspv=zeros(13,sspv);

t=0;

for i=1:11
    if bspv(2,i)==1
        t=t+1;
        cspv(:,t)=bspv(:,i);
    end
end


cspv=unique(cspv','rows')';


%c of 13 cross 6 matrix is generated in which the first row is the swara
%from which Graha bhedam is done
%the rest 12 rows are the 1, 0 binary notation
%there are totally 6 columns which talk about the 6 new scales formed by
%graha bhedam, the 6 columns start with 1, i.e there is definitely a
%Shadjam.

moverall_spv=[];

cjanyaspv=[];

[norows_cspv,nocols_cspv]=size(cspv);

cjanyaspv_colindex=0;

for i=1:nocols_cspv
 
    tempcolvector=cspv(:,i);
    temprowvector=tempcolvector';
    
    for janyano=1:norows_cspv
        if isequal(cell2mat(app.J(janyano,2)),temprowvector(1,[2:13]))
            cjanyaspv_colindex = cjanyaspv_colindex + 1;
            cjanyaspv(1,cjanyaspv_colindex) = temprowvector(1,1);
            cjanyaspv(2,cjanyaspv_colindex) = janyano;
            cjanyaspv([3:14],cjanyaspv_colindex)=temprowvector(1,[2:13])';
        end
    end
            
    
end

norows_cjanyaspv=0;

app.nocols_cjanyaspv=0;

[norows_cjanyaspv,app.nocols_cjanyaspv]=size(cjanyaspv);

fprintf('With Shadjam & Panchama Varjyam, number of ragas produced on Graha Bhedam are %d\n',app.nocols_cjanyaspv)

%Production of cshruti matrix with zeros for the shifted ragas
cshrutispv=zeros(15,app.nocols_cjanyaspv);

for i = 1:app.nocols_cjanyaspv
    cshrutispv(1,i)=cjanyaspv(1,i);
    cshrutispv(2,i)=cjanyaspv(2,i);
    cshrutispv([3:14],i)=cjanyaspv([3:14],i).*app.shruti.*((1.059463)^(cjanyaspv(1,i)-1));
    cshrutispv(15,i)=523.25.*((1.059463)^(cjanyaspv(1,i)-1));

end


%Generation of cshrutinonzeros matrix

noelements_in_cshrutispvnonzeros=length(nonzeros(cshrutispv(:,1)));
cshrutispvnonzeros = zeros(noelements_in_cshrutispvnonzeros,app.nocols_cjanyaspv);


for i=1:app.nocols_cjanyaspv
    cshrutispvnonzeros(1,i)=cshrutispv(1,i);
    cshrutispvnonzeros(2,i)=cshrutispv(2,i);
    cshrutispvnonzeros([3:noelements_in_cshrutispvnonzeros],i)=nonzeros(cshrutispv([3:15],i));
end


[reff_spv,ceff_spv]=size(cshrutispvnonzeros);

rtemp_spv=reff_spv-2;
rangec1_spv=zeros(rtemp_spv, 16001,ceff_spv);


%Graha_Bhedam_From_Swara=strings([ceff,1]);
%MelakartaName=strings([ceff,1]);  
           

SNo=zeros(ceff_spv,1);          
Graha_Bhedam_From_Swara=cell(ceff_spv,1);
Raga_Name=cell(ceff_spv,1);


for tdim=1:ceff_spv


notes=[cshrutispvnonzeros([2:reff_spv],tdim);0;(fliplr(cshrutispvnonzeros([2:reff_spv],tdim)'))'];
no_of_notes=length(notes);

%  Generating the sound of the raga

Fs        =  12000;  % sampling frequency
repeat    =  1;     % no. of repeatition you want to hear the raga
tim       =  1;      % hear each swara/note for 1 second


%duration of each swara
dur = ones(no_of_notes,1);


% amplitude of sound of each note
amp   = 48 * ones(no_of_notes,1)./60;


% no. of harmonics
nharm = 8;   % (if nharm is 2, note freq f and 2f are added to sound)

% scaling the amplitude of harmonics (exp(1-n)/fac))
fac   = 1;
m_spv = [];
for j = 1:repeat
for i = 1:no_of_notes
x = generate_tone(notes(i),tim*dur(i),amp(i),Fs,nharm,fac);
m_spv = horzcat(m_spv,x);

end
end


noteno_spv=cshrutispvnonzeros(1,tdim);

note_name_spv='Ga 1';

for i=1:12
    if i==noteno_spv
        note_name_spv=N(i,1);
    end
end

%note_name=cell2mat(notename);
%note_name_string is the swara name like Sa, Ri, Ga, Ma

note_name_spv_string=note_name_spv{1};

janya_index_no = cshrutispvnonzeros(2,tdim);

janya_name_incell=app.J(janya_index_no,1);
janya_name=janya_name_incell{1};


SNo(tdim,1)=tdim;
Graha_Bhedam_From_Swara(tdim,1)=note_name_spv;
Raga_Name(tdim,1)=janya_name_incell;


fprintf('%d. The notes when shifted from the swara %s give the scale of raga %s \n', tdim,note_name_spv_string,janya_name) 
sound(m_spv,Fs)

pause(20)

end


%All converted to cell form

S_No=num2cell(SNo);

Graha_Bhedam_Ragas_after_Sa_Pa_Varjyam=table(S_No, Graha_Bhedam_From_Swara, Raga_Name)

Uitable2_data=[Graha_Bhedam_From_Swara Raga_Name];


f = figure('Position', [100 100 752 250]);
t2 = uitable('Parent', f, 'Position', [25 50 700 200], 'Data', Uitable2_data);
t2.ColumnName = {'Graha Bhedam from Swara', 'YieldsRaga'};
t2.ColumnWidth = {'auto',300};
t2.FontSize=12;


% Closure of work on Shadjama Panchama Varjyam


        end

        % NonotesButton button pushed function
        function NonotesButtonButtonPushed(app)
            app.Nonotesinraga.Value= app.no_of_notes_in_raga;
        end

        % Nograhabhedamragas_melakartaButton button pushed function
        function Nograhabhedamragas_melakartaButtonButtonPushed(app)
            app.Nograhabhedamragas_melakarta.Value=app.nocols_cmelakarta;
        end

        % DropDown2 value changed function
        function DropDown2ValueChanged(app, event)
            value = app.DropDown2.Value;
            
            atemp=zeros(1,12);
            
            app.J=cell(18, 2);

            
            load('testovaci_janyaragas', 'janyaragas')
            
            app.J=janyaragas;

            
[app.norows_J,~]=size(app.J);

           
            for i=1:app.norows_J
                if strcmp(value, app.J(i,1))
                 atemp = cell2mat(app.J(i,2));
                end
            end
            app.aj = atemp;


        end

        % InputRagaListen button pushed function
        function InputRagaListenButtonPushed(app)


shrutitemp = [261.62, 277.18, 293.66, 311.12, 329.62, 349.22, 369.99, 391.99, 415.30, 440.00, 466.16, 493.88];
app.shruti=shrutitemp';

ainteriminterim=app.aj;
ainterim=ainteriminterim';

bshruti=zeros(12,1);

bshruti=ainterim.*app.shruti;


noelements_in_bshrutinonzeros=length(nonzeros(bshruti(:,1)));
bshrutinonzeros = zeros(noelements_in_bshrutinonzeros,1);
bshrutinonzeros=nonzeros(bshruti(:,1));
bshrutinonzeros = [bshrutinonzeros; 523.25];
no_of_notes_bshrutinonzeros=length(bshrutinonzeros);

[reff,ceff]=size(bshrutinonzeros);


notes=[bshrutinonzeros;0;(fliplr(bshrutinonzeros'))'];
no_of_notes=length(notes);

%  Generating the sound of the raga

Fs        =  12000;  % sampling frequency
repeat    =  1;     % no. of repeatition you want to hear the raga
tim       =  1;      % hear each swara/note for 1 second


%duration of each swara
dur = ones(no_of_notes,1);


% amplitude of sound of each note
amp   = 48 * ones(no_of_notes,1)./60;


% no. of harmonics
nharm = 8;   % (if nharm is 2, note freq f and 2f are added to sound)

% scaling the amplitude of harmonics (exp(1-n)/fac))
fac   = 1;
sig = [];
for j = 1:repeat
for i = 1:no_of_notes
x = generate_tone(notes(i),tim*dur(i),amp(i),Fs,nharm,fac);
sig = horzcat(sig,x);
end
end


app.no_of_notes_in_raga_janya=reff-1;


fprintf('The input raga sounds like\n')
pause(3)
sound(sig,Fs)

filename='Mohanam_raga.wav';
audiowrite(filename,sig,Fs);

pause(20)


        end

        % OnGrahaBhedam button pushed function
        function OnGrahaBhedamJanya(app)


            shrutitemp = [261.62, 277.18, 293.66, 311.12, 329.62, 349.22, 369.99, 391.99, 415.30, 440.00, 466.16, 493.88];
app.shruti=shrutitemp';

%Circular shifting

ainterim=app.aj';


b=zeros(13,11);

for i=1:11

b(1,i) = i+1;
b([2:13],i)= circshift(ainterim,12-i);

end


s=sum(b(2,1:11));

c=zeros(13,s);

t=0;

for (i=1:11)
    if b(2,i)==1
        t=t+1;
        c(:,t)=b(:,i);
    end
end


c=unique(c','rows')';


%c of 13 cross 6 matrix is generated in which the first row is the swara
%from which Graha bhedam is done
%the rest 12 rows are the 1, 0 binary notation
%there are totally 6 columns which talk about the 6 new scales formed by
%graha bhedam, the 6 columns start with 1, i.e there is definitely a
%Shadjam.

cjanya=[];

[norows_c,nocols_c]=size(c);

cjanya_colindex=0;

for i=1:nocols_c
 
    tempcolvector=c(:,i);
    temprowvector=tempcolvector';
    
    for janyano=1:app.norows_J
        if isequal(cell2mat(app.J(janyano,2)),temprowvector(1,[2:13]))
            cjanya_colindex = cjanya_colindex + 1;
            cjanya(1,cjanya_colindex) = temprowvector(1,1);
            cjanya(2,cjanya_colindex) = janyano;
            cjanya([3:14],cjanya_colindex)=temprowvector(1,[2:13])';
        end
    end
            
    
end

norows_cjanya=0;

app.nocols_cjanya=0;

[norows_cjanya,app.nocols_cjanya]=size(cjanya);


fprintf('The number of ragas produced on Graha Bhedam are %d\n',app.nocols_cjanya)


%Production of cshruti matrix with zeros for the shifted ragas
cshruti=zeros(15,app.nocols_cjanya);

for i = 1:app.nocols_cjanya
    cshruti(1,i)=cjanya(1,i);
    cshruti(2,i)=cjanya(2,i);
    cshruti([3:14],i)=cjanya([3:14],i).*app.shruti.*((1.059463)^(cjanya(1,i)-1));
    cshruti(15,i)= 523.25.*((1.059463)^(cjanya(1,i)-1));
end


%Generation of cshrutinonzeros matrix

noelements_in_cshrutinonzeros=length(nonzeros(cshruti(:,1)));
cshrutinonzeros = zeros(noelements_in_cshrutinonzeros,app.nocols_cjanya);

for i=1:app.nocols_cjanya
    cshrutinonzeros(1,i)=cshruti(1,i);
    cshrutinonzeros(2,i)=cshruti(2,i);
    cshrutinonzeros([3:noelements_in_cshrutinonzeros],i)=nonzeros(cshruti([3:15],i));
end


[reff,ceff]=size(cshrutinonzeros);


%Tabulation of Note Number and Swara Name

N=cell(12, 2);
          
           N(1,:)={'Sa', 1};
           N(2,:)={'Ri 1', 2};
           N(3,:)={'Ri 2', 3};
           N(4,:)={'Ga 2', 4};
           N(5,:)={'Ga 3', 5};
           N(6,:)={'Ma 1', 6};
           N(7,:)={'Ma 2', 7};
           N(8,:)={'Pa', 8};
           N(9,:)={'Dha 1', 9};
           N(10,:)={'Dha 2', 10};
           N(11,:)={'Ni 2', 11};
           N(12,:)={'Ni 3', 12};


SNo=zeros(ceff,1);          
Graha_Bhedam_From_Swara=cell(ceff,1);
Raga_Name=cell(ceff,1);

moverall=[];

for tdim=1:ceff

%sound vector

notes=[cshrutinonzeros([3:reff],tdim);0;(fliplr(cshrutinonzeros([3:reff],tdim)'))'];
no_of_notes=length(notes);

%  Generating the sound of the raga

Fs        =  12000;  % sampling frequency
repeat    =  1;     % no. of repeatition you want to hear the raga
tim       =  1;      % hear each swara/note for 1 second


%duration of each swara
dur = ones(no_of_notes,1);


% amplitude of sound of each note
amp   = 48 * ones(no_of_notes,1)./60;


% no. of harmonics
nharm = 8;   % (if nharm is 2, note freq f and 2f are added to sound)

% scaling the amplitude of harmonics (exp(1-n)/fac))
fac   = 1;
m = [];
for j = 1:repeat
for i = 1:no_of_notes
x = generate_tone(notes(i),tim*dur(i),amp(i),Fs,nharm,fac);
m = horzcat(m,x);
end
end


noteno=cshrutinonzeros(1,tdim);

%for initializing with random note Ga1
note_name='Ga 1';

for i=1:12
    if i==noteno
        note_name=N(i,1);
    end
end

%note_name=cell2mat(notename);
%note_name_string is the swara name like Sa, Ri, Ga, Ma

note_name_string=note_name{1};

janya_no = cshrutinonzeros(2,tdim);

raga_name_incell=app.J(janya_no,1);
raga_name=raga_name_incell{1};


SNo(tdim,1)=tdim;
Graha_Bhedam_From_Swara(tdim,1)=note_name;
Raga_Name(tdim,1)=raga_name_incell;


fprintf('%d. The notes when shifted from the swara %s give the scale of raga %s \n', tdim,note_name_string,raga_name) 
sound(m,Fs)


pause(20)


end


%Cell to string
Shruthi_Bhedam_From_Swara=char(Graha_Bhedam_From_Swara);
Raga_Name_char=char(Raga_Name);

%tout = table('size',[ceff 4],'VariableTypes',{'int8','string','int8','string'});          
% tout = table(SNo,Shruthi_Bhedam_From_Swara,Yields_MelakartaNo,Melakarta_Raga);


%All converted to cell form

S_No=num2cell(SNo);

Graha_Bhedam_Ragas=table(S_No, Graha_Bhedam_From_Swara, Raga_Name)

%f=uifigure;
%uit = uitable(f,'Data',tout_cell);
%uit.ColumnName = {'SNo','Swara','Mela No','Raga'};


Uitable_data_3=[Graha_Bhedam_From_Swara Raga_Name];

% f=uifigure;
% uit = uitable('Parent', f,'Data',magic(10))


f3 = figure('Position', [100 100 752 250]);

t3 = uitable('Parent', f3, 'Position', [25 50 700 200], 'Data', Uitable_data_3);
t3.ColumnName = {'Graha Bhedam from Swara', 'Yields Raga'};
t3.ColumnWidth = {'auto', 300};
t3.FontSize=12;


        end

        % NoNotesinRagaButton button pushed function
        function NoNotesinRagaButtonButtonPushed(app)
            app.NoNotesInRaga.Value= app.no_of_notes_in_raga_janya;
        end

        % NoGrahaBhedamRagasButton button pushed function
        function NoGrahaBhedamRagasButtonButtonPushed(app)
            app.NoGrahaBhedamRagas.Value=app.nocols_cjanya;
        end

        % Nograhabhedamragas_SaPaVarjyamButton button pushed function
        function Nograhabhedamragas_SaPaVarjyamButtonButtonPushed(app)
            app.Nograhabhedamragas_SaPaVarjyam.Value=app.nocols_cjanyaspv;
        end

        % DropDown3 value changed function
        function DropDown3ValueChanged(app, event)
            value = app.DropDown3.Value;
            
           
            atemp1=zeros(1,12);
            atemp2=zeros(1,12);
            
            app.Aj=cell(6, 3);

            
            load ('testovaci_ajanya.mat', 'ajanya')
            
            app.Aj=ajanya;

            for i=1:6
                if strcmp(value, app.Aj(i,1))
                 atemp1 = cell2mat(app.Aj(i,2));
                 atemp2 = cell2mat(app.Aj(i,3));
                 
                end
            end
            app.a1=atemp1;
            app.a2=atemp2;


        end

        % Button button pushed function
        function ButtonButtonPushed(app)


shrutitemp = [261.62, 277.18, 293.66, 311.12, 329.62, 349.22, 369.99, 391.99, 415.30, 440.00, 466.16, 493.88];
app.shruti=shrutitemp';

%Arohana passage

a1interiminterim=app.a1;
a1interim=a1interiminterim';

b1shruti=zeros(12,1);

b1shruti=a1interim.*app.shruti;

noelements_in_b1shrutinonzeros=length(nonzeros(b1shruti(:,1)));
b1shrutinonzeros = zeros(noelements_in_b1shrutinonzeros,1);
b1shrutinonzeros=nonzeros(b1shruti(:,1));
b1shrutinonzeros = [b1shrutinonzeros; 523.25];

no_of_notes_b1shrutinonzeros=length(b1shrutinonzeros);
[reff,ceff]=size(b1shrutinonzeros);


%Avarohana passage

a2interiminterim=app.a2;
a2interim=a2interiminterim';

b2shruti=zeros(12,1);

b2shruti=a2interim.*app.shruti;

noelements_in_b2shrutinonzeros=length(nonzeros(b2shruti(:,1)));
b2shrutinonzeros = zeros(noelements_in_b2shrutinonzeros,1);
b2shrutinonzeros=nonzeros(b2shruti(:,1));
b2shrutinonzeros = [b2shrutinonzeros; 523.25];

no_of_notes_b2shrutinonzeros=length(b2shrutinonzeros);
[reff2,ceff2]=size(b2shrutinonzeros);


notes=[b1shrutinonzeros;0;(fliplr(b2shrutinonzeros'))'];
no_of_notes=length(notes);


%  Generating the sound of the raga

Fs        =  12000;  % sampling frequency
repeat    =  1;     % no. of repeatition you want to hear the raga
tim       =  1;      % hear each swara/note for 1 second


%duration of each swara
dur = ones(no_of_notes,1);


% amplitude of sound of each note
amp   = 48 * ones(no_of_notes,1)./60;


% no. of harmonics
nharm = 8;   % (if nharm is 2, note freq f and 2f are added to sound)

% scaling the amplitude of harmonics (exp(1-n)/fac))
fac   = 1;
sig = [];
for j = 1:repeat
for i = 1:no_of_notes
x = generate_tone(notes(i),tim*dur(i),amp(i),Fs,nharm,fac);
sig = horzcat(sig,x);
end
end


% app.no_of_notes_in_raga=reff-1;


fprintf('The input raga sounds like\n')
pause(3)
sound(sig,Fs)
pause(20)

kedar='kedaragowla_raga.wav';

audiowrite(kedar,sig,Fs);


        end

        % Button3 button pushed function
        function Button3ButtonPushed(app)


shrutitemp = [261.62, 277.18, 293.66, 311.12, 329.62, 349.22, 369.99, 391.99, 415.30, 440.00, 466.16, 493.88];
app.shruti=shrutitemp';

%Circular shifting

a1interim=app.a1';
a2interim=app.a2';

b1=zeros(13,11);
b2=zeros(13,11);

for i=1:11

b1(1,i) = i+1;
b1([2:13],i)= circshift(a1interim,12-i);


b2(1,i) = i+1;
b2([2:13],i)= circshift(a2interim,12-i);


end


s1=sum(b1(2,1:11));
s2=sum(b2(2,1:11));


c1=zeros(13,s1);
c2=zeros(13,s1);


t=0;

for (i=1:11)
    if b1(2,i)==1
        t=t+1;
        c1(:,t)=b1(:,i);
        c2(:,t)=b2(:,i);
    end


end


c1=unique(c1','rows')';
c2=unique(c2','rows')';


c1janya=[];

c2janya=[];


[norows_c1,nocols_c1]=size(c1);

[norows_c2,nocols_c2]=size(c2);


c1janya_colindex=0;

c2janya_colindex=0;


for i=1:nocols_c1
 
    temp1colvector=c1(:,i);
    temp1rowvector=temp1colvector';

    temp2colvector=c2(:,i);
    temp2rowvector=temp2colvector';


    for janyano=1:6
        if (isequal(cell2mat(app.Aj(janyano,2)),temp1rowvector(1,[2:13])) & isequal(cell2mat(app.Aj(janyano,3)),temp2rowvector(1,[2:13])) )
            c1janya_colindex = c1janya_colindex + 1;
            c1janya(1,c1janya_colindex) = temp1rowvector(1,1);
            c1janya(2,c1janya_colindex) = janyano;
            c1janya([3:14],c1janya_colindex)=temp1rowvector(1,[2:13])';
        
        
          c2janya_colindex = c2janya_colindex + 1;
            c2janya(1,c2janya_colindex) = temp2rowvector(1,1);
            c2janya(2,c2janya_colindex) = janyano;
            c2janya([3:14],c2janya_colindex)=temp2rowvector(1,[2:13])';
        
                
        end
    end
            
    
end

norows_c1janya=0;

app.nocols_c1janya=0;

[norows_c1janya,app.nocols_c1janya]=size(c1janya);


norows_c2janya=0;

app.nocols_c2janya=0;

[norows_c2janya,app.nocols_c2janya]=size(c2janya);


fprintf('The number of ragas produced on Graha Bhedam are %d\n',app.nocols_c1janya)


%Production of cshruti matrix with zeros for the shifted ragas
c1shruti=zeros(15,app.nocols_c1janya);

for i = 1:app.nocols_c1janya
    c1shruti(1,i)=c1janya(1,i);
    c1shruti(2,i)=c1janya(2,i);
    c1shruti([3:14],i)=c1janya([3:14],i).*app.shruti.*((1.059463)^(c1janya(1,i)-1));
    c1shruti(15,i)= 523.25.*((1.059463)^(c1janya(1,i)-1));
end


c2shruti=zeros(15,app.nocols_c2janya);

for i = 1:app.nocols_c2janya
    c2shruti(1,i)=c2janya(1,i);
    c2shruti(2,i)=c2janya(2,i);
    c2shruti([3:14],i)=c2janya([3:14],i).*app.shruti.*((1.059463)^(c2janya(1,i)-1));
    c2shruti(15,i)= 523.25.*((1.059463)^(c2janya(1,i)-1));
end


%Generation of cshrutinonzeros matrix

noelements_in_c1shrutinonzeros=length(nonzeros(c1shruti(:,1)));
c1shrutinonzeros = zeros(noelements_in_c1shrutinonzeros,app.nocols_c1janya);

for i=1:app.nocols_c1janya
    c1shrutinonzeros(1,i)=c1shruti(1,i);
    c1shrutinonzeros(2,i)=c1shruti(2,i);
    c1shrutinonzeros([3:noelements_in_c1shrutinonzeros],i)=nonzeros(c1shruti([3:15],i));
end


noelements_in_c2shrutinonzeros=length(nonzeros(c2shruti(:,1)));
c2shrutinonzeros = zeros(noelements_in_c2shrutinonzeros,app.nocols_c2janya);

for i=1:app.nocols_c2janya
    c2shrutinonzeros(1,i)=c2shruti(1,i);
    c2shrutinonzeros(2,i)=c2shruti(2,i);
    c2shrutinonzeros([3:noelements_in_c2shrutinonzeros],i)=nonzeros(c2shruti([3:15],i));
end


[reff,ceff]=size(c1shrutinonzeros);
[reff2,ceff2]=size(c2shrutinonzeros);


%Tabulation of Note Number and Swara Name

N=cell(12, 2);
          
           N(1,:)={'Sa', 1};
           N(2,:)={'Ri 1', 2};
           N(3,:)={'Ri 2', 3};
           N(4,:)={'Ga 2', 4};
           N(5,:)={'Ga 3', 5};
           N(6,:)={'Ma 1', 6};
           N(7,:)={'Ma 2', 7};
           N(8,:)={'Pa', 8};
           N(9,:)={'Dha 1', 9};
           N(10,:)={'Dha 2', 10};
           N(11,:)={'Ni 2', 11};
           N(12,:)={'Ni 3', 12};


SNo=zeros(ceff,1);          
Graha_Bhedam_From_Swara=cell(ceff,1);
Raga_Name=cell(ceff,1);


for tdim=1:ceff


%sound vector


notes=[c1shrutinonzeros([3:reff],tdim);0;(fliplr(c2shrutinonzeros([3:reff2],tdim)'))'];
no_of_notes=length(notes);

%  Generating the sound of the raga

Fs        =  12000;  % sampling frequency
repeat    =  1;     % no. of repeatition you want to hear the raga
tim       =  1;      % hear each swara/note for 1 second


%duration of each swara
dur = ones(no_of_notes,1);


% amplitude of sound of each note
amp   = 48 * ones(no_of_notes,1)./60;


% no. of harmonics
nharm = 8;   % (if nharm is 2, note freq f and 2f are added to sound)

% scaling the amplitude of harmonics (exp(1-n)/fac))
fac   = 1;
m = [];
for j = 1:repeat
for i = 1:no_of_notes
x = generate_tone(notes(i),tim*dur(i),amp(i),Fs,nharm,fac);
m = horzcat(m,x);
end
end


noteno=c1shrutinonzeros(1,tdim);

%for initializing with random note Ga1
note_name='Ga 1';

for i=1:12
    if i==noteno
        note_name=N(i,1);
    end
end

%note_name=cell2mat(notename);
%note_name_string is the swara name like Sa, Ri, Ga, Ma

note_name_string=note_name{1};

janya_no = c1shrutinonzeros(2,tdim);

raga_name_incell=app.Aj(janya_no,1);
raga_name=raga_name_incell{1};


SNo(tdim,1)=tdim;
Graha_Bhedam_From_Swara(tdim,1)=note_name;
Raga_Name(tdim,1)=raga_name_incell;


fprintf('%d. The notes when shifted from the swara %s give the scale of raga %s \n', tdim,note_name_string,raga_name) 

sound(m,Fs)

audiowrite('gs.wav',m,Fs)


pause(20)


end


%Cell to string
Shruthi_Bhedam_From_Swara=char(Graha_Bhedam_From_Swara);
Raga_Name_char=char(Raga_Name);

%tout = table('size',[ceff 4],'VariableTypes',{'int8','string','int8','string'});          
% tout = table(SNo,Shruthi_Bhedam_From_Swara,Yields_MelakartaNo,Melakarta_Raga);


%All converted to cell form

S_No=num2cell(SNo);

Graha_Bhedam_Ragas=table(S_No, Graha_Bhedam_From_Swara, Raga_Name)

%f=uifigure;
%uit = uitable(f,'Data',tout_cell);
%uit.ColumnName = {'SNo','Swara','Mela No','Raga'};


Uitable_data_3=[Graha_Bhedam_From_Swara Raga_Name];

% f=uifigure;
% uit = uitable('Parent', f,'Data',magic(10))


f3 = figure('Position', [100 100 752 250]);

t3 = uitable('Parent', f3, 'Position', [25 50 700 200], 'Data', Uitable_data_3);
t3.ColumnName = {'Graha Bhedam from Swara', 'Yields Raga'};
t3.ColumnWidth = {'auto', 300};
t3.FontSize=12;


        end

        % Button2 button pushed function
        function Button2ButtonPushed(app)
            app.NoGrahaBhedamRagasAj.Value=app.nocols_c1janya;
            
        end

        % UIFigure close request function
        function UIFigureCloseRequest(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest);
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Melakarta Ragas';
            app.Label = uilabel(app.Tab);
            app.Label.Text = 'Explore Graha Bhedam for Melakarta Ragas';
            app.Label2 = uilabel(app.Tab);
            app.Label2.Text = '';
            app.LabelDropDown = uilabel(app.Tab);
            app.LabelDropDown.Text = 'Choose the raga';
            app.DropDown = uidropdown(app.Tab);
            app.DropDown.Items = {'Kanakaangi', 'Rathnaangi', 'Gaanamurthy', 'Vanaspathy', 'Manavathy', 'Taanaroopi', 'Senavathy', 'Hanumathodi', 'Dhenuka', 'Naatakapriya', 'Kokilapriya', 'Roopavathy', 'Gaayagapriya', 'Vakulabharanam', 'Maayamaalavagowla', 'Chakravaaham', 'Sooryakantham', 'Haatakambari', 'Jhankaardhwani', 'Natabhairavi', 'Kiravani', 'Kharahapriyaa', 'Gowrimanohari', 'Varunapriya', 'Maararanjani', 'Charukesi', 'Sarasaangi', 'Harikaambhoji', 'Dheerasankaraabharanam', 'Naagaanandini', 'Yaagapriya', 'Raagavardhani', 'Gaangeyabhooshani', 'Vaagatheeswari', 'Shoolini', 'Chalanaata', 'Saalakam', 'Jalaarnavam', 'Jhalavaraali', 'Navaneetham', 'Paavani', 'Raghupriya', 'Gavaambhodhi', 'Bhavapriya', 'Subhapantuvaraali', 'Shadvidhamaargini', 'Suvarnaangi', 'Divyamani', 'Dhavalaambari', 'Naamanaaraayani', 'Kaamavardhani', 'Raamapriya', 'Gamanasrama', 'Visvambari', 'Shyaamalaangi', 'Shanmugapriya', 'Simhendramadhyamam', 'Hemavathy', 'Dharmavathy', 'Neetimathi', 'Kaantaamani', 'Rishabhapriya', 'Lataangi', 'Vaachaspathy', 'Mechakalyani', 'Chitraambari', 'Sucharithram', 'Jyothiswaroopini', 'Dhaatuvarthini', 'Naasikabhooshani', 'Kosalam', 'Rasigapriya'};
            app.DropDown.Editable = 'on';
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @choose_dropdown, true);
            app.DropDown.Value = 'Kanakaangi';
            app.Nonotesinraga = uieditfield(app.Tab, 'numeric');
            app.Nonotesinraga.Editable = 'off';
            app.LabelNumericEditField = uilabel(app.Tab);
            app.LabelNumericEditField.Text = '';
            app.Nograhabhedamragas_melakarta = uieditfield(app.Tab, 'numeric');
            app.Nograhabhedamragas_melakarta.Editable = 'off';
            app.NonotesButton = uibutton(app.Tab, 'push');
            app.NonotesButton.ButtonPushedFcn = createCallbackFcn(app, @NonotesButtonButtonPushed);
            app.NonotesButton.Text = 'The number of Notes in this Raga:';
            app.Nograhabhedamragas_melakartaButton = uibutton(app.Tab, 'push');
            app.Nograhabhedamragas_melakartaButton.ButtonPushedFcn = createCallbackFcn(app, @Nograhabhedamragas_melakartaButtonButtonPushed);
            app.Nograhabhedamragas_melakartaButton.Text = 'No of Melakarta Ragas:';
            app.Nograhabhedamragas_SaPaVarjyamButton = uibutton(app.Tab, 'push');
            app.Nograhabhedamragas_SaPaVarjyamButton.ButtonPushedFcn = createCallbackFcn(app, @Nograhabhedamragas_SaPaVarjyamButtonButtonPushed);
            app.Nograhabhedamragas_SaPaVarjyamButton.Text = 'No of Ragas by Sa Pa Varjyam';
            app.Label3 = uilabel(app.Tab);
            app.Label3.Text = 'Graha Bhedam Ragas:';
            app.Nograhabhedamragas_SaPaVarjyam = uieditfield(app.Tab, 'numeric');
            app.Nograhabhedamragas_SaPaVarjyam.Editable = 'off';
            app.InputRaga = uibutton(app.Tab, 'push');
            app.InputRaga.ButtonPushedFcn = createCallbackFcn(app, @Input_raga);
            app.InputRaga.Text = 'Listen to the input raga';
            app.OnShrutiBhedam = uibutton(app.Tab, 'push');
            app.OnShrutiBhedam.ButtonPushedFcn = createCallbackFcn(app, @Shrutibhedam);
            app.OnShrutiBhedam.Text = {'Click to see what '; 'happens if you do '; 'Graha Bhedam to this Raga'};
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Symmetric Janya Ragas';
            app.LabelDropDown2 = uilabel(app.Tab2);
            app.LabelDropDown2.Text = 'Choose the Raga';
            app.DropDown2 = uidropdown(app.Tab2);
            app.DropDown2.Items = {'Sudha Saaveri', 'Sudha Dhanyaasi', 'Madhyamaavathy', 'Hindolam', 'Mohanam', 'Ghambeera Naata', 'Bhoopalam', 'Naagaswaraavali', 'Hamsadwani', 'Karnataka Sudha Saveri', 'Aabhogi', 'Valaji', 'Brindavani', 'Jog', 'Sivaranjani', 'Revathy', 'Sunaadhavinodhini', 'Hamsanaadham'};
            app.DropDown2.Editable = 'on';
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @DropDown2ValueChanged, true);
            app.DropDown2.Value = 'Sudha Saaveri';
            app.InputRagaListen = uibutton(app.Tab2, 'push');
            app.InputRagaListen.ButtonPushedFcn = createCallbackFcn(app, @InputRagaListenButtonPushed);
            app.InputRagaListen.Text = 'Listen to the Input Raga';
            app.NoNotesinRagaButton = uibutton(app.Tab2, 'push');
            app.NoNotesinRagaButton.ButtonPushedFcn = createCallbackFcn(app, @NoNotesinRagaButtonButtonPushed);
            app.NoNotesinRagaButton.Text = 'The number of notes in this raga are';
            app.NoGrahaBhedamRagasButton = uibutton(app.Tab2, 'push');
            app.NoGrahaBhedamRagasButton.ButtonPushedFcn = createCallbackFcn(app, @NoGrahaBhedamRagasButtonButtonPushed);
            app.NoGrahaBhedamRagasButton.Text = 'Number of Graha Bhedam Ragas';
            app.NoNotesInRaga = uieditfield(app.Tab2, 'numeric');
            app.NoGrahaBhedamRagas = uieditfield(app.Tab2, 'numeric');
            app.OnGrahaBhedam = uibutton(app.Tab2, 'push');
            app.OnGrahaBhedam.ButtonPushedFcn = createCallbackFcn(app, @OnGrahaBhedamJanya);
            app.OnGrahaBhedam.Text = {'Click to see what'; 'Ragas you get on'; 'Graha Bhedam'};
            app.Label5 = uilabel(app.Tab2);
            app.Label5.Text = 'Explore Graha Bhedam for Symmetric Janya Ragas';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Units = 'pixels';
            app.Tab3.Title = 'Asymmetric Janya Ragas';
            app.Label4 = uilabel(app.Tab3);
            app.Label4.Text = 'Choose the Raga';
            app.DropDown3 = uidropdown(app.Tab3);
            app.DropDown3.Items = {'Aarabi', 'Aabheri', 'Dhanyaasi', 'Kedaara Gowla', 'Mohana Kalyaani', 'Saalaka Bhairavi'};
            app.DropDown3.Editable = 'on';
            app.DropDown3.ValueChangedFcn = createCallbackFcn(app, @DropDown3ValueChanged, true);
            app.DropDown3.Value = 'Aarabi';
            app.Button = uibutton(app.Tab3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed);
            app.Button.Text = 'Listen to the Input Raga';
            app.Button2 = uibutton(app.Tab3, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2ButtonPushed);
            app.Button2.Text = 'Number of Graha Bhedam Ragas';
            app.NoGrahaBhedamRagasAj = uieditfield(app.Tab3, 'numeric');
            app.Button3 = uibutton(app.Tab3, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3ButtonPushed);
            app.Button3.Text = {'Click to see what Ragas '; 'you get on Graha Bhedam'};
            app.Label6 = uilabel(app.Tab3);
            app.Label6.Text = 'Explore Graha Bhedam for Asymmetric Janya Ragas';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end